classdef SENC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SENCImagesButton       matlab.ui.control.Button
        AnotomyImageButton     matlab.ui.control.Button
        StrainImageButton      matlab.ui.control.Button
        FunctionalImageButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Global variables % Description
        file
        path
        info
        Y
        nSizeRows
        nSizeCols
        LTpst
        HTpst
        LTimage
        HTimage
        SizeN1
        Aimage
        Apst
        Spst
        anotomyBtn
        strainBtn
        strain
        fcnBtn
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SENCImagesButton
        function SENCImagesButtonPushed(app, event)
            close all;
            [app.file, app.path]=uigetfile('*.dcm', 'Select Multiple Files', 'MultiSelect', 'on');
            app.info = dicominfo(app.file{1});
            nRow = app.info.Rows;
            nCol = app.info.Columns;
            app.nSizeRows = cast(uint16(nRow),  'double');
            app.nSizeCols = cast(uint16(nCol),  'double');
            if isequal(app.file,0)
                disp('User selected Cancel');
            else
%             mkdir('LTImages');
%             mkdir('HTImages');
            for i=1:numel(app.file)/2
                app.LTimage{i} = dicomread(fullfile(app.path,app.file{2*i-1}));
                app.HTimage{i} = dicomread(fullfile(app.path,app.file{2*i}));
%                 copyfile(app.file{2*i-1}, 'LTImages')
%                 copyfile(app.file{2*i}, 'HTImages')
            end
            text1 = sprintf('%s %s', 'Low', 'Tune');
            text2 = sprintf('%s %s', 'High', 'Tune');
            app.LTpst = uiaxes(app.UIFigure, 'Position', [0 180 180 180]);
            app.HTpst = uiaxes(app.UIFigure, 'Position', [0 30 180 180]);
            label1 = uilabel(app.UIFigure, 'Text', text1, 'Position', [60 345 80 30]);
            label2 = uilabel(app.UIFigure, 'Text', text2, 'Position', [60 45 80 30]);
            s=1;
            maxNum = numel(app.file)/2;
            while s<=maxNum
                imshow(app.LTimage{s}, [], 'parent', app.LTpst);
                imshow(app.HTimage{s}, [], 'parent', app.HTpst);
                pause(0.5)
                if s == maxNum
                    s=1;
                end
                if app.anotomyBtn == true
                    s=s;
                else
                    s=s+1;
                end 
            end
            set(app.LTpst, 'visible','off');
            set(app.HTpst, 'visible','off');
            end
close all;
        end

        % Button pushed function: AnotomyImageButton
        function AnotomyImageButtonPushed(app, event)
            app.anotomyBtn = true;
%             mkdir('AtmImages')
            app.SizeN1 = numel(app.file)-1;
            for i=1:app.SizeN1
                app.Aimage{i} = dicomread(fullfile(app.path,app.file{i})) + dicomread(fullfile(app.path,app.file{i+1}));
            end
            text = sprintf('%s %s', 'Anotomy', 'Image');
            app.Apst = uiaxes(app.UIFigure, 'Position', [160 180 180 180]);
            set(app.Apst, 'visible', 'off');
            label = uilabel(app.UIFigure, 'Text', text, 'Position', [210 345 100 30]);
            t=1;
            while t<=app.SizeN1
                imshow(app.Aimage{t}, [], 'parent', app.Apst);
                pause(0.5)
                if t == app.SizeN1
                    t=1;
                end
                if app.strainBtn == true;
                    t=t;
                else
                    t=t+1;
                end 
            end 
close all;
        end

        % Button pushed function: StrainImageButton
        function StrainImageButtonPushed(app, event)
            app.strainBtn = true;
            f_0 = 0.2625;
            f_L = 0.25;
            f_H = 0.375;
            img1 = app.LTimage{1};
            img2 = app.HTimage{1};
            for i=1:app.nSizeRows
                for j=1:app.nSizeCols
                    f = (f_L*img1(i,j)+f_H*img2(i,j))./(img1(i,j)+img2(i,j));
                    strain = 100*f_0./ (f-1)
                end
            end
            figure
            imshow(strain,[]);
%             app.strain = zeros(app.nSizeRows, app.nSizeCols, app.SizeN1);
%             for k=1:1
%                 img1 = double(dicomread(fullfile(app.path,app.file{k})));
%                 img2 = double(dicomread(fullfile(app.path,app.file{k+1})));
% %                 img1 = 255*(img1+min(img1(:)))/(max(img1(:))+min(img1(:)));
% %                 img2 = 255*(img2+min(img2(:)))/(max(img2(:))+min(img2(:)));
%             figure, imshow(img1,[])
%                 if rem(k,2)==1
%                     app.strain(:,:,k) = (100*f_0)./((((f_L*img1)+(f_H*img2))./(img1+img2))-1);
%                 elseif rem(k,2)==0
%                     app.strain(:,:,k) = (100*f_0)./((((f_L*img2)+(f_H*img1))./(img1+img2))-1);
%                 end
%             end
%             disp(app.strain(:,:,14))
%             text = sprintf('%s %s', 'Strain', 'Image');
%             app.Spst = uiaxes(app.UIFigure, 'Position', [320 180 180 180]);
%             set(app.Spst, 'visible', 'off');
%             label = uilabel(app.UIFigure, 'Text', text, 'Position', [370 345 100 30]);
%             u=1;
%             while u<=app.SizeN1
%                 imshow(app.strain(:,:,u), [], 'parent', app.Spst);
%                 pause(0.5)
%                 if u == app.SizeN1
%                     u=1;
%                 end
%                 if app.fcnBtn == true;
%                     u=u;
%                 else
%                     u=u+1;
%                 end 
%             end 
close all;
        end

        % Button pushed function: FunctionalImageButton
        function FunctionalImageButtonPushed(app, event)
            app.fcnBtn = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SENCImagesButton = uibutton(app.UIFigure, 'push');
            app.SENCImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SENCImagesButtonPushed, true);
            app.SENCImagesButton.Text = {'SENC Images'; ''};
            app.AnotomyImageButton = uibutton(app.UIFigure, 'push');
            app.AnotomyImageButton.ButtonPushedFcn = createCallbackFcn(app, @AnotomyImageButtonPushed, true);
            app.AnotomyImageButton.Text = 'Anotomy Image';
            app.StrainImageButton = uibutton(app.UIFigure, 'push');
            app.StrainImageButton.ButtonPushedFcn = createCallbackFcn(app, @StrainImageButtonPushed, true);
            app.StrainImageButton.Text = {'Strain Image'; ''};
            app.FunctionalImageButton = uibutton(app.UIFigure, 'push');
            app.FunctionalImageButton.ButtonPushedFcn = createCallbackFcn(app, @FunctionalImageButtonPushed, true);
            app.FunctionalImageButton.Text = {'Functional Image'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SENC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end